classdef gliomaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExtractConventionalFeaturesButton  matlab.ui.control.Button
        ToggleTumorMaskSwitch       matlab.ui.control.Switch
        ToggleTumorMaskSwitchLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ImageAcquisitionProtocolButtonGroup  matlab.ui.container.ButtonGroup
        T2Button                    matlab.ui.control.ToggleButton
        T2FLAIRButton               matlab.ui.control.ToggleButton
        T1GdButton                  matlab.ui.control.ToggleButton
        T1Button                    matlab.ui.control.ToggleButton
        SliceSlider                 matlab.ui.control.Slider
        BrowseButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        directory;          % Directory path containing .h5 files
        filedata;           % List of filedata
        mri_axes;           % Axes displaying the MRI
        slice_images;       % Slice image data from .h5 files - 155x4x240x240
        slice_masks;        % Slice mask data from .h5 files - 155x3x240x240
        slice_shown;        % Index of the currently shown slice
        slice_masks_conventional; %for conventional data 
        slice_images_conventional; %for conventional data 
        conventional_data_arr; %array to store all conventiona data
        conventional_dir    % directory path convnetional features analysed from
        mri_image;          % Image data currently being displayed - 240x240x3
        protocol;           % Currently selected image acquisition protocol
        protocol_enum;      % Enumeration set at startup of protocol name to slice index
        mask_status;        % Boolean - true if tumor mask is on, false if off
    end
    
    methods (Access = private)

        
        % Read dataset from the directory path
        function readDataset(app)
            % Grab file list by creating a mask of dir entries that are files
            % and applying that mask to the list of the names of the files
            % Then it transposes the array to be a column (using ')
            app.filedata = dir(app.directory);
            filelist = {app.filedata(~[app.filedata.isdir]).name}';

            % Remove all files that are not .h5 files
            % https://stackoverflow.com/a/15604522
            % Throw an error if 154 .h5 files are not found
            filelist = filelist(~cellfun('isempty', regexpi(filelist, '\.h5$')));
            if length(filelist) ~= 155
                errordlg("Error: Unable to use directory - must have 155 .h5 files in directory");
                return;
            end

            % natsort function credit - Stephen Cobeldick
            % https://au.mathworks.com/matlabcentral/fileexchange/34464-customizable-natural-order-sort
            filelist = natsort(filelist);
            
            % Formatting for the table to display the files
            app.UITable.Data = filelist;
            app.UITable.ColumnEditable = false;

            % Get the volume number
            volume = getVolume(app,filelist{1});
       
            % Read data from all files
            for i = 1:155
                filepath = sprintf('%s/volume_%s_slice_%d.h5', app.directory, volume, i-1);
                app.slice_images{i} = h5read(filepath, '/image');
                app.slice_masks{i} = h5read(filepath, '/mask');
            end
            displayImage(app, 75);
        end

        function displayImage(app, slice)
            data = app.slice_images{slice};
            protocol_index = app.protocol_enum(app.protocol);
            % Transpose data so in form 240x240x1
            data = permute(data, [2 3 1]);
            % Normalize the image channel to 0-1, then convert to uint8
            image = data(:,:,protocol_index);
            image = (image-min(image(:))) ./ (max(image(:)));
            app.mri_image = im2uint8(image);
            imshow(app.mri_image, "Parent", app.mri_axes);
            
            % Set app properties to new values
            app.slice_shown = slice;
            app.SliceSlider.Value = slice;

            % If tumor mask is toggled on, update tumor mask
            if app.mask_status
                displayMask(app);
            end
        end
        
        function displayMask(app)
            mask = app.slice_masks{app.slice_shown};
            % Transpose data so in form 240x240x3, then to grayscale
            mask = permute(mask, [2 3 1]);
            mask = any(mask, 3);
            mask = mat2gray(mask);
          
            % Set shown image to the image with mask overlaid
            overlaid_image = labeloverlay(app.mri_image, mask,"Colormap", 'winter',"Transparency", 0.5);
            app.mri_image = overlaid_image;
            imshow(app.mri_image, "Parent", app.mri_axes);
        end

        function volume = getVolume(app,filename)

            % Get the volume number
            volume = split(filename,'_');
            volume = volume{2};

            return;
        end

        function total_area = calculateArea(app)

            total_area = 0;

            % Count the number of 1's in the slice_masks_conventional
            for i = 1:155
                current_slice = app.slice_masks_conventional{i};
                current_slice = any(current_slice, 1);
                current_area = sum(current_slice(:));

                if current_area > total_area
                    total_area = current_area;
                end
            end

            return;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial value of output image
            app.mri_axes = uiaxes(app.UIFigure);
            app.mri_axes.Position = [320 80 240 240];
            app.mri_axes.XTick = [];
            app.mri_axes.YTick = [];
            app.mri_axes.Box = 'on';

            % Initialize slices data objects
            app.slice_images = cell(155, 1);
            app.slice_masks = cell(155, 1);

            app.slice_shown = -1; % set to -1 if no slice is being shown
            app.protocol = app.ImageAcquisitionProtocolButtonGroup.SelectedObject.Text;
            app.protocol_enum = containers.Map({'T2-FLAIR', 'T1', 'T1Gd', 'T2'}, {1, 2, 3, 4});
            app.mask_status = strcmp(app.ToggleTumorMaskSwitch.Value, "On");
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selected_dir = uigetdir('', 'Select a Directory');
            % Only call displayFiles if selected_dir points to a new directory
            if selected_dir ~= 0 & ~strcmp(selected_dir, app.directory)
                app.directory = selected_dir;
                readDataset(app);
            end
        end

        % Selection changed function: ImageAcquisitionProtocolButtonGroup
        function ImageAcquisitionProtocolButtonGroupSelectionChanged(app, event)
            app.protocol = app.ImageAcquisitionProtocolButtonGroup.SelectedObject.Text;
            if app.slice_shown ~= -1
                displayImage(app, app.slice_shown);
            end
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            slice = round(event.Value);
            if app.slice_shown ~= -1
                displayImage(app, slice);
            end            
        end

        % Value changed function: ToggleTumorMaskSwitch
        function ToggleTumorMaskSwitchValueChanged(app, event)
            app.mask_status = strcmp(app.ToggleTumorMaskSwitch.Value, "On");
            if app.slice_shown ~= -1
                if app.mask_status
                    displayMask(app)
                else
                    displayImage(app, app.slice_shown);
                end
            end
        end

        % Button pushed function: ExtractConventionalFeaturesButton
        function ExtractConventionalFeaturesButtonPushed(app, event)


            % Create cell array for the headers
            headers = {'Volume_ID', 'area', 'diameter', 'out_layer_involvement'};
            % Write headers and data to a CSV file
            filename = 'conventional_features.csv';
            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file for writing: %s', filename);
            end

            % Write headers
            fprintf(fid, '%s,%s,%s,%s\n', headers{:});


            numRows = 0; %number of rows
            numCols = 4;  %Example number of columns
            app.conventional_data_arr = zeros(numRows, numCols); % Preallocate with 0 values
            

            selected_dir = uigetdir('', 'Select a Directory');            
            app.conventional_dir = selected_dir;
    
            % Get a list of all files in the directory
            files = dir(app.conventional_dir);
            
            for i = 1:length(files)
                % Exclude '.' and '..' directories
                if strcmp(files(i).name, '.') || strcmp(files(i).name, '..') || strcmp(files(i).name, '.DS_Store')
                    continue;
                end
    
                volume = 0;
                maxDiameter = 0;
                total_outer_pixels = 0;
                tumor_outer_pixels = 0;
                
                if files(i).isdir 
                    
                    current_dir = sprintf('%s/%s', files(i).folder, files(i).name);
                    
                    current_filedata = dir(current_dir);
                    current_filelist = {current_filedata(~[current_filedata.isdir]).name}';

                    % natsort function credit - Stephen Cobeldick
                    % https://au.mathworks.com/matlabcentral/fileexchange/34464-customizable-natural-order-sort
                    current_filelist = natsort(current_filelist);

                    % Get the volume number
                    volume = getVolume(app,current_filelist{1});

                    for j = 1:155
                        current_filepath = sprintf('%s/volume_%s_slice_%d.h5', current_dir, volume, j-1);
                        app.slice_images_conventional{j} = h5read(current_filepath, '/image');
                        app.slice_masks_conventional{j} = h5read(current_filepath, '/mask');
                        
                        mask = app.slice_masks_conventional{j};
                        % Transpose data so in form 240x240x3, then to grayscale
                        mask = permute(mask, [2 3 1]);
                        mask = any(mask, 3);
                        
                        data = app.slice_images_conventional{j};
                        % Transpose data so in form 240x240x1
                        data = permute(data, [2 3 1]);
                        % Normalize the image channel to 0-1, then convert to uint8
                        image = data(:,:,1);
                        image = (image-min(image(:))) ./ (max(image(:)));

                        % Get the dimensions of the mask
                        [rows, cols] = size(mask);

                        %define a thickness for outer layer
                        thickness = 5;

                        brain_mask = image > 0;

                        % Use morphological operations to find the outer layer of the brain
                        se = strel('disk', thickness); % Create a disk-shaped structuring element

                        % Erode the brain mask to get the inner boundary
                        eroded_brain_mask = imerode(brain_mask, se);
            
                        % Subtract the eroded mask from the original brain mask to get the outer layer
                        outer_layer_mask = brain_mask & ~eroded_brain_mask;
        
                         % Count the number of tumor pixels in the outer layer
                        tumor_outer_pixels = tumor_outer_pixels + sum(mask(outer_layer_mask) == 1);
                        % Count the total number of pixels in the outer layer
                        total_outer_pixels = total_outer_pixels + sum(outer_layer_mask(:));
    
        
                        % Find coordinates of the tumor (where mask is 1)
                        [rows, cols] = find(mask == 1);
                        %combine coordinates into a matrix
                        tumorCoordinates = [rows, cols];
                  
                        if isempty(tumorCoordinates)
                            continue; % Skip if no tumor is found in this file
                        end
                        
                        if isequal(j,120) 
                            disp(app.slice_masks_conventional{j})
                        end
                        
                        % Perform PCA on the tumor pixel coordinates
                        [coeff, score, ~] = pca(tumorCoordinates);
    
                        % Project the tumor pixels onto the first principal component
                        projectedPixels = score(:, 1);
    
                        % Calculate the maximum diameter along the first principal component
                        maxDiameterCurrent = max(projectedPixels) - min(projectedPixels);
            
                        % Update the maximum diameter if the current is larger
                        if maxDiameterCurrent > maxDiameter
                            maxDiameter = maxDiameterCurrent;
                        end

                    end
            
                end

                % Calculate the percentage of outer layer involvement
                percent_outer_involvement = (tumor_outer_pixels / total_outer_pixels) * 100;
    
                %get total max area of tumor
                total_area = calculateArea(app);

                num_vol = str2double(volume);

                app.conventional_data_arr(end+1,:) = [num_vol,total_area,maxDiameter,percent_outer_involvement];
                
            end

 
            writematrix(app.conventional_data_arr, filename, 'WriteMode', 'append');

            % Close the file
            fclose(fid);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Load an Image from File'};
            app.BrowseButton.Text = 'Browse...';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [1 155];
            app.SliceSlider.MajorTicks = [1 155];
            app.SliceSlider.MajorTickLabels = {'1', '155'};
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Value = 75;
            app.ImageAcquisitionProtocolButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageAcquisitionProtocolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageAcquisitionProtocolButtonGroupSelectionChanged, true);
            app.ImageAcquisitionProtocolButtonGroup.Title = 'Image Acquisition Protocol';
            app.T1Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1Button.Text = 'T1';
            app.T1Button.Value = true;
            app.T1GdButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1GdButton.Text = 'T1Gd';
            app.T2FLAIRButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2FLAIRButton.Text = 'T2-FLAIR';
            app.T2Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2Button.Text = 'T2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ToggleTumorMaskSwitchLabel = uilabel(app.UIFigure);
            app.ToggleTumorMaskSwitchLabel.Text = 'Toggle Tumor Mask';
            app.ToggleTumorMaskSwitch = uiswitch(app.UIFigure, 'slider');
            app.ToggleTumorMaskSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleTumorMaskSwitchValueChanged, true);
            app.ExtractConventionalFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractConventionalFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractConventionalFeaturesButtonPushed, true);
            app.ExtractConventionalFeaturesButton.Text = 'Extract Conventional Features ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gliomaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end